classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        AddumbrellaButton      matlab.ui.control.Button
        CoverOnOffButton       matlab.ui.control.StateButton
        OpenStatusSlider       matlab.ui.control.Slider
        OpenStatusSliderLabel  matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Umbrella % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddumbrellaButton
        function AddUmbrella(app, event)
            ax = app.UIAxes;
            ax.DataAspectRatio = [1,1,1];
            app.Umbrella = MakeUmbrella(ax);
            app.Umbrella.Cover.FaceColor = 'r';
        end

        % Value changed function: OpenStatusSlider
        function OpenedStatus(app, event)
            value = app.OpenStatusSlider.Value;
            t = value/100;
            app.Umbrella.Open(t);
        end

        % Value changing function: OpenStatusSlider
        function OpeningStatus(app, event)
            changingValue = event.Value;
            t = changingValue/100;
            app.Umbrella.Open(t);
        end

        % Value changed function: CoverOnOffButton
        function Cover_On_Off(app, event)
            value = app.CoverOnOffButton.Value;
            if(value)
                app.Umbrella.Cover.FaceColor = 'r';
                app.Umbrella.Edge.Color = 'k';
            else
                app.Umbrella.Cover.FaceColor = 'none';
                app.Umbrella.Edge.Color = 'none';
            end
            drawnow;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {659, 659};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {317, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OpenStatusSliderLabel = uilabel(app.LeftPanel);
            app.OpenStatusSliderLabel.Text = 'OpenStatus';
            app.OpenStatusSlider = uislider(app.LeftPanel);
            app.OpenStatusSlider.ValueChangedFcn = createCallbackFcn(app, @OpenedStatus, true);
            app.OpenStatusSlider.ValueChangingFcn = createCallbackFcn(app, @OpeningStatus, true);
            app.OpenStatusSlider.Value = 100;
            app.CoverOnOffButton = uibutton(app.LeftPanel, 'state');
            app.CoverOnOffButton.ValueChangedFcn = createCallbackFcn(app, @Cover_On_Off, true);
            app.CoverOnOffButton.Text = 'Cover On/Off';
            app.CoverOnOffButton.Value = true;
            app.AddumbrellaButton = uibutton(app.LeftPanel, 'push');
            app.AddumbrellaButton.ButtonPushedFcn = createCallbackFcn(app, @AddUmbrella, true);
            app.AddumbrellaButton.Text = 'Add umbrella';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X axis')
            ylabel(app.UIAxes, 'Y axis')
            zlabel(app.UIAxes, 'Z axis')
            app.UIAxes.XLim = [-4 4];
            app.UIAxes.YLim = [-4 4];
            app.UIAxes.ZLim = [-1 6];
            app.UIAxes.XTick = [-4 4];
            app.UIAxes.YTick = [-4 4];
            app.UIAxes.ZTick = [-1 6];
            app.UIAxes.ZTickLabel = {'-1'; '6'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end